classdef ProjectApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        HourlyLoadButton               matlab.ui.control.Button
        PVoutButton                    matlab.ui.control.Button
        SOCButton                      matlab.ui.control.Button
        NetLoadButton                  matlab.ui.control.Button
        UtilityPriceButton             matlab.ui.control.Button
        BillButton                     matlab.ui.control.Button
        FuzzyOutputButton              matlab.ui.control.Button
        ClearthegraphButton            matlab.ui.control.Button
        WithPVpanelButton              matlab.ui.control.Button
        RunthesimulationLabel          matlab.ui.control.Label
        WithoutPVpanelButton           matlab.ui.control.Button
        SelecttheDataforplottingLabel  matlab.ui.control.Label
        WithPVButton                   matlab.ui.control.Button
        WithoutPVButton                matlab.ui.control.Button
        SimulationstopstatusLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        load;
        pvout;
        soc;
        netload;
        utprice;
        bill;
        fzout;
        time;
        wksowithoutpv;
        wksowithpv;
        ldw;
        pvw;
        nvlw;
        ldwt;
        pvwt;
        nvlwt;        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Graphical results';
            grid(app.UIAxes, 'on')
        end

        % Button pushed function: HourlyLoadButton
        function HourlyLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.load)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: PVoutButton
        function PVoutButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.pvout)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: UtilityPriceButton
        function UtilityPriceButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.utprice)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: NetLoadButton
        function NetLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.netload)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: SOCButton
        function SOCButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.soc)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: FuzzyOutputButton
        function FuzzyOutputButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.fzout)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: BillButton
        function BillButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.bill)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: WithPVpanelButton
        function WithPVpanelButtonPushed(app, event)
            %title(app.UIAxes, 'Simulation Running.....')
            app.wksowithpv = sim('ModelWithPV.slx','ReturnWorkspaceOutputs','on');
            app.time = app.wksowithpv.tout';
            app.ldw = app.wksowithpv.load.Data;
            app.pvw = app.wksowithpv.pv_power.Data;
            
            if max(app.ldw) > max(app.pvw)
                app.nvlw = max(app.ldw);
            else
                app.nvlw = max(app.pvw);
            end
            app.SimulationstopstatusLabel.Text = 'Simulation with PV ended';
        end

        % Button pushed function: ClearthegraphButton
        function ClearthegraphButtonPushed(app, event)
            app.UIAxes.cla;
            %grid(app.UIAxes, 'on')
        end

        % Button pushed function: WithoutPVpanelButton
        function WithoutPVpanelButtonPushed(app, event)
            app.wksowithoutpv = sim('ModelWithoutPV.slx','ReturnWorkspaceOutputs','on');
            app.time = app.wksowithoutpv.tout*100';
            app.ldwt = app.wksowithoutpv.load.Data;
            app.pvwt = app.wksowithoutpv.pv_power.Data;
            
            if max(app.ldwt) > max(app.pvwt)
                app.nvlwt = max(app.ldwt);
            else
                app.nvlwt = max(app.pvwt);
            end
            app.SimulationstopstatusLabel.Text = 'Simulation without PV ended';
        end

        % Button pushed function: WithoutPVButton
        function WithoutPVButtonPushed(app, event)
            app.SimulationstopstatusLabel.Text = 'Simulation status';
            app.pvout = app.pvwt'*100/app.nvlwt;
            app.load = app.ldwt'*100/app.nvlwt;
            app.soc = app.wksowithoutpv.soc.Data;
            app.netload = app.wksowithoutpv.net_load_out.Data'*100/app.nvlwt;
            app.utprice = app.wksowithoutpv.price_out.Data*100;
            app.bill = app.wksowithoutpv.bill.Data'*0.1/97.28;
            app.fzout = app.wksowithoutpv.fz_out.Data'*100/app.nvlwt;
            title(app.UIAxes,'Without PV working')
        end

        % Button pushed function: WithPVButton
        function WithPVButtonPushed(app, event)
            app.SimulationstopstatusLabel.Text = 'Simulation status';
            app.pvout = app.pvw'*100/app.nvlw;
            app.load = app.ldw'*100/app.nvlw;
            app.soc = app.wksowithpv.soc.Data;
            app.netload = app.wksowithpv.net_load_out.Data'*100/app.nvlw;
            app.utprice = app.wksowithpv.price_out.Data*100;
            app.bill = app.wksowithpv.bill.Data'*0.1/97.28;
            app.fzout = app.wksowithpv.fz_out.Data'*100/app.nvlw;
            title(app.UIAxes,'With PV working')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time in hours')
            ylabel(app.UIAxes, 'Percentage')
            app.HourlyLoadButton = uibutton(app.UIFigure, 'push');
            app.HourlyLoadButton.ButtonPushedFcn = createCallbackFcn(app, @HourlyLoadButtonPushed, true);
            app.HourlyLoadButton.Text = 'Hourly Load';
            app.PVoutButton = uibutton(app.UIFigure, 'push');
            app.PVoutButton.ButtonPushedFcn = createCallbackFcn(app, @PVoutButtonPushed, true);
            app.PVoutButton.Text = 'PV. out';
            app.SOCButton = uibutton(app.UIFigure, 'push');
            app.SOCButton.ButtonPushedFcn = createCallbackFcn(app, @SOCButtonPushed, true);
            app.SOCButton.Text = 'SOC';
            app.NetLoadButton = uibutton(app.UIFigure, 'push');
            app.NetLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NetLoadButtonPushed, true);
            app.NetLoadButton.Text = 'Net Load';
            app.UtilityPriceButton = uibutton(app.UIFigure, 'push');
            app.UtilityPriceButton.ButtonPushedFcn = createCallbackFcn(app, @UtilityPriceButtonPushed, true);
            app.UtilityPriceButton.Text = 'Utility Price';
            app.BillButton = uibutton(app.UIFigure, 'push');
            app.BillButton.ButtonPushedFcn = createCallbackFcn(app, @BillButtonPushed, true);
            app.BillButton.Text = 'Bill';
            app.FuzzyOutputButton = uibutton(app.UIFigure, 'push');
            app.FuzzyOutputButton.ButtonPushedFcn = createCallbackFcn(app, @FuzzyOutputButtonPushed, true);
            app.FuzzyOutputButton.Text = 'Fuzzy Output';
            app.ClearthegraphButton = uibutton(app.UIFigure, 'push');
            app.ClearthegraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearthegraphButtonPushed, true);
            app.ClearthegraphButton.Text = 'Clear the graph';
            app.WithPVpanelButton = uibutton(app.UIFigure, 'push');
            app.WithPVpanelButton.ButtonPushedFcn = createCallbackFcn(app, @WithPVpanelButtonPushed, true);
            app.WithPVpanelButton.Text = 'With PV panel';
            app.RunthesimulationLabel = uilabel(app.UIFigure);
            app.RunthesimulationLabel.Text = 'Run the simulation';
            app.WithoutPVpanelButton = uibutton(app.UIFigure, 'push');
            app.WithoutPVpanelButton.ButtonPushedFcn = createCallbackFcn(app, @WithoutPVpanelButtonPushed, true);
            app.WithoutPVpanelButton.Text = 'Without PV panel';
            app.SelecttheDataforplottingLabel = uilabel(app.UIFigure);
            app.SelecttheDataforplottingLabel.Text = 'Select the Data for plotting';
            app.WithPVButton = uibutton(app.UIFigure, 'push');
            app.WithPVButton.ButtonPushedFcn = createCallbackFcn(app, @WithPVButtonPushed, true);
            app.WithPVButton.Text = 'With PV';
            app.WithoutPVButton = uibutton(app.UIFigure, 'push');
            app.WithoutPVButton.ButtonPushedFcn = createCallbackFcn(app, @WithoutPVButtonPushed, true);
            app.WithoutPVButton.Text = 'Without PV';
            app.SimulationstopstatusLabel = uilabel(app.UIFigure);
            app.SimulationstopstatusLabel.Text = 'Simulation stop status';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectApp1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end