classdef SimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        HourlyLoadButton     matlab.ui.control.Button
        PVoutButton          matlab.ui.control.Button
        SOCButton            matlab.ui.control.Button
        NetLoadButton        matlab.ui.control.Button
        UtilityPriceButton   matlab.ui.control.Button
        BillButton           matlab.ui.control.Button
        FuzzyOutputButton    matlab.ui.control.Button
        ClearthegraphButton  matlab.ui.control.Button
        RunSimulationButton  matlab.ui.control.Button
    end


    properties (Access = private)
        load;
        pvout;
        soc;
        netload;
        utprice;
        bill;
        fzout;
        time;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Button pushed function: HourlyLoadButton
        function HourlyLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.load)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: ClearthegraphButton
        function ClearthegraphButtonPushed(app, event)
            app.UIAxes.cla;
        end

        % Button pushed function: PVoutButton
        function PVoutButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.pvout)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: SOCButton
        function SOCButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.soc)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: NetLoadButton
        function NetLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.netload)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: UtilityPriceButton
        function UtilityPriceButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.utprice)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: BillButton
        function BillButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.bill)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: FuzzyOutputButton
        function FuzzyOutputButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.fzout)
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            sim('final_model_v2.slx')
            filename = 'data.xlsx';
            app.time = xlsread(filename, 'sheet1', 'A1:A167');
            app.load = xlsread(filename,'sheet1','B1:B167');
            app.pvout = xlsread(filename,'sheet1','C1:C167');
            app.soc = xlsread(filename,'sheet1','D1:D167');
            app.netload = xlsread(filename,'sheet1','E1:E167');
            app.utprice = xlsread(filename,'sheet1','F1:F167');
            app.bill = xlsread(filename,'sheet1','G1:G167');
            app.fzout = xlsread(filename,'sheet1','H1:H167');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Percentage')
            app.HourlyLoadButton = uibutton(app.UIFigure, 'push');
            app.HourlyLoadButton.ButtonPushedFcn = createCallbackFcn(app, @HourlyLoadButtonPushed, true);
            app.HourlyLoadButton.Text = 'Hourly Load';
            app.PVoutButton = uibutton(app.UIFigure, 'push');
            app.PVoutButton.ButtonPushedFcn = createCallbackFcn(app, @PVoutButtonPushed, true);
            app.PVoutButton.Text = 'PV. out';
            app.SOCButton = uibutton(app.UIFigure, 'push');
            app.SOCButton.ButtonPushedFcn = createCallbackFcn(app, @SOCButtonPushed, true);
            app.SOCButton.Text = 'SOC';
            app.NetLoadButton = uibutton(app.UIFigure, 'push');
            app.NetLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NetLoadButtonPushed, true);
            app.NetLoadButton.Text = 'Net Load';
            app.UtilityPriceButton = uibutton(app.UIFigure, 'push');
            app.UtilityPriceButton.ButtonPushedFcn = createCallbackFcn(app, @UtilityPriceButtonPushed, true);
            app.UtilityPriceButton.Text = 'Utility Price';
            app.BillButton = uibutton(app.UIFigure, 'push');
            app.BillButton.ButtonPushedFcn = createCallbackFcn(app, @BillButtonPushed, true);
            app.BillButton.Text = 'Bill';
            app.FuzzyOutputButton = uibutton(app.UIFigure, 'push');
            app.FuzzyOutputButton.ButtonPushedFcn = createCallbackFcn(app, @FuzzyOutputButtonPushed, true);
            app.FuzzyOutputButton.Text = 'Fuzzy Output';
            app.ClearthegraphButton = uibutton(app.UIFigure, 'push');
            app.ClearthegraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearthegraphButtonPushed, true);
            app.ClearthegraphButton.Text = 'Clear the graph';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end